classdef BeamApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MaterialDropDown               matlab.ui.control.DropDown
        MaterialDropDownLabel          matlab.ui.control.Label
        MomentofInertiaEditFieldLabel_2  matlab.ui.control.Label
        MomentofInertiaEditField       matlab.ui.control.NumericEditField
        MomentofInertiaEditFieldLabel  matlab.ui.control.Label
        LoadLocationinSlider           matlab.ui.control.Slider
        LoadLocationinSliderLabel      matlab.ui.control.Label
        LoadpoundsSlider               matlab.ui.control.Slider
        LoadpoundsSliderLabel          matlab.ui.control.Label
        FlangeThicknessinSlider        matlab.ui.control.Slider
        FlangeThicknessinSliderLabel   matlab.ui.control.Label
        WebThicknessinSlider           matlab.ui.control.Slider
        WebThicknessinSliderLabel      matlab.ui.control.Label
        BeamWidthinSlider              matlab.ui.control.Slider
        BeamWidthinSliderLabel         matlab.ui.control.Label
        BeamHeightinSlider             matlab.ui.control.Slider
        BeamHeightinSliderLabel        matlab.ui.control.Label
        BeamLengthinSlider             matlab.ui.control.Slider
        BeamLengthinSliderLabel        matlab.ui.control.Label
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        % initialize all values to appropriate amounts
        BeamHeight = 12;        % height of beam in inches
        BeamWidth = 12;         % width of beam in inches
        FlangeThickness = 2;    % thickness of top and bottom flanges in inches
        WebThickness = 2;       % thickness of web in in
        BeamLength = 100;       % length of beam in in
        LoadLocation = 0;       % center load
        Load = 0;               % set load to 0
        ElasticModulus = 30e6;  % elatic modulus in PSI
        
        
    end
    
    methods (Access = public)
        
        
        function results = Update(app,flag)
            % update function:
            %      
            % updates all plots, calculates displacements, moment of
            % inertia etc. 
            % flag argument tells Update which parts of program to update
            % 1 - update beam cross section, moment of inertia calc
            
       
            if(flag == 1)
                %%%
                % plotting beam cross section
                %%%
                
                % two sets of x coords for left and right web lines
                web_height_x1 = [-app.WebThickness/2 -app.WebThickness/2];
                web_height_x2 = [app.WebThickness/2 app.WebThickness/2];
                % one set of y coords for left and right web lines
                web_height_y1 = [(-app.BeamHeight/2 + app.FlangeThickness/4), (app.BeamHeight/2 - app.FlangeThickness/4)];
                
                % plots lines for top and bottom of beam
                % one set of x coords from left to right of beam
                beam_width_x1 = [-app.BeamWidth/2 app.BeamWidth/2];
                % two set of y coords for top and bottom of beam
                beam_width_y1 = [app.BeamHeight/2 app.BeamHeight/2];
                beam_width_y2 = [-app.BeamHeight/2 -app.BeamHeight/2];
                
                % plot lines on top of bottom flange and bottom of top flange
                % xcoords for left and right inside flanges
                leftflange_width_x1 = [-app.BeamWidth/2 -app.WebThickness/2];
                rightflange_width_x1 = [app.WebThickness/2 app.BeamWidth/2];
                % ycoords for both flanges (you can just take the negative
                insideflange_height_y1 = [(app.BeamHeight/2 - app.FlangeThickness/4) (app.BeamHeight/2 - app.FlangeThickness/4)];
                
                % plot left and right side of flanges
                flangeedge_width_x1 = [-app.BeamWidth/2 -app.BeamWidth/2];
                flangeedge_height_y1 = [app.BeamHeight/2 (app.BeamHeight/2 - app.FlangeThickness/4)];
                
                % clear plot
                plot(app.UIAxes_2,0,0);
                
                % hold on
                hold(app.UIAxes_2,'on');
                
                % call one plot function instead, people claim is faster
                
                plot(app.UIAxes_2, web_height_x1,web_height_y1,'k',...
                                   web_height_x2,web_height_y1,'k',...
                                   beam_width_x1,beam_width_y1,'k',...
                                   beam_width_x1,beam_width_y2,'k',...
                                   leftflange_width_x1,insideflange_height_y1,'k',...
                                   rightflange_width_x1,insideflange_height_y1,'k',...
                                   leftflange_width_x1,-insideflange_height_y1,'k',...
                                   rightflange_width_x1,-insideflange_height_y1,'k',...
                                   flangeedge_width_x1,flangeedge_height_y1,'k',...
                                   -flangeedge_width_x1,flangeedge_height_y1,'k',...
                                   flangeedge_width_x1,-flangeedge_height_y1,'k',...
                                   -flangeedge_width_x1,-flangeedge_height_y1,'k'...
                                   );
                
                %hold off
                hold(app.UIAxes_2,'off')
                
                %%%
                % end plotting beam cross section
                %%%
                
            end
            
            %%%
            % Moment of Inertia Calculator
            %%%
            
            MOI1 = 1/12 * (app.WebThickness) * (app.BeamHeight - app.FlangeThickness)^3;
            MOI2 = 1/12 * (app.BeamWidth) * (app.FlangeThickness/2)^3 ...
                + (app.BeamWidth * app.FlangeThickness/2)*(app.BeamHeight/2 - app.FlangeThickness/4)^2;
            I =  MOI1 + 2*MOI2;
            app.MomentofInertiaEditField.Value = I;
            
            %%%
            % determine modulus of elasticity
            %%%
            
            E = app.ElasticModulus;
            
            %%%
            % beam displacment code
            %%%
            
            %clear plot
            plot(app.UIAxes,0,0);
            
            %hold on
            hold(app.UIAxes,'on');
            
            %plot beam length
            num = 1000;
            
            beam_xvals1 = linspace(0,app.LoadLocation,num/2);
            beam_xvals2 = linspace(app.LoadLocation,app.BeamLength,num/2);
            
            % beam displacement equation
            c = app.LoadLocation;
            cpr = app.BeamLength - app.LoadLocation;
            
            % for left half
            w1 =  -app.Load*app.BeamLength^3/(6*E*I) * ...
                  ( (cpr/app.BeamLength).* ((beam_xvals1)./ app.BeamLength) .* ...
                   (1 - cpr^2/app.BeamLength^2 - (beam_xvals1).^2./ app.BeamLength^2));
            
            % for right half
            w2 = -app.Load*app.BeamLength^3/(6*E*I) * ...
                   ( (cpr/app.BeamLength) .* ((beam_xvals2) / app.BeamLength).* ...
                    (1 - (cpr^2/app.BeamLength^2) - (beam_xvals2).^2./ app.BeamLength^2) + ...
                                    (((beam_xvals2) - c)/app.BeamLength).^3);
            
            % use various translations to plot the deformed shape of the
            % beam
            plot(app.UIAxes, beam_xvals1 - app.BeamLength/2,w1,'k',beam_xvals2- app.BeamLength/2,w2,'k',...
                beam_xvals1 - app.BeamLength/2,w1-app.BeamHeight,'k',beam_xvals2- app.BeamLength/2,w2-app.BeamHeight,'k',...
                beam_xvals1 - app.BeamLength/2,w1-app.FlangeThickness/2,'k',beam_xvals2- app.BeamLength/2,w2-app.FlangeThickness/2,'k',...
                beam_xvals1 - app.BeamLength/2,w1-app.BeamHeight + app.FlangeThickness/2,'k',...
                beam_xvals2- app.BeamLength/2,w2-app.BeamHeight + app.FlangeThickness/2,'k',...
                [-app.BeamLength/2 -app.BeamLength/2],[0 -app.BeamHeight],'k',...
                [app.BeamLength/2 app.BeamLength/2],[0 -app.BeamHeight],'k','LineWidth',2);
            
            %plot load arrow
            
            % for plotting load arrow
            
            location = app.LoadLocation - app.BeamLength/2;
            x1_arrow = [location location];
            y1_arrow = [app.BeamHeight/2 1.5*app.BeamHeight];
            x2_arrow = [0.8*location location];
            x3_arrow = [1.2*location location];
            y2_arrow = [(app.BeamHeight/2)*1.5 app.BeamHeight/2];
            
            arrowwidth = 3*(app.Load/10000) + 1;
            
            plot(app.UIAxes,x3_arrow,y2_arrow,'b','LineWidth',arrowwidth);
            plot(app.UIAxes,x1_arrow,y1_arrow,'b','LineWidth',arrowwidth);
            plot(app.UIAxes,x2_arrow,y2_arrow,'b','LineWidth',arrowwidth);
            
            %%%
            % end beam displacment plotting
            %%%
            
            %%%
            % support plotting code
            %%%
            
            % plot two supports at end of beam with pin supports
            
            % x1 is end of beam
            % x2 is left by 10%
            % x3 is right by 10%
            support_x1 = -app.BeamLength/2;
            support_x2 = 1.1* (-app.BeamLength/2); 
            support_x3 = 0.9* (-app.BeamLength/2); 
            
            % y1 is end of beam
            % y2 is below end of beam
            support_y1 = -app.BeamHeight;
            support_y2 = 2 * (-app.BeamHeight);
            
            plot(app.UIAxes,[support_x1 support_x2], [support_y1 support_y2],'k',...
                            [support_x1 support_x3], [support_y1 support_y2],'k',...
                            [support_x2 support_x3], [support_y2 support_y2],'k',...
                            [-support_x1 -support_x2], [support_y1 support_y2],'k',...
                            [-support_x1 -support_x3], [support_y1 support_y2],'k',...
                            [-support_x2 -support_x3], [support_y2 support_y2],'k');
            
            %%%
            % end support plotting code
            %%% 
            
            
            %hold off
            hold(app.UIAxes,'off');
            
            weblim = app.BeamWidth;
            app.WebThicknessinSlider.Limits(2) = weblim;
            
            flangelim = app.BeamHeight;
            app.FlangeThicknessinSlider.Limits(2) = flangelim;
            
            if(app.BeamLength/2 < .6*app.BeamLength/2)
                loadlim = app.BeamLength/2;
                app.LoadLocationinSlider.Limits(1) = -loadlim;
                app.LoadLocationinSlider.Limits(2) = loadlim;
            else
                app.LoadLocationinSlider.Limits(1) = -.6*app.BeamLength/2;
                app.LoadLocationinSlider.Limits(2) = .6*app.BeamLength/2;
            end
            
            % calculate moment of inertia and display it in the moment of
            % inertia field
            % since beam is symmetric we take total moment of inertia
            % and subtract two missing sections
            
%             MOI = 1/12 * app.BeamWidth*app.BeamHeight^3 -...
%                 2 * (1/12 * (app.BeamWidth/2 - app.WebThickness/2)...
%                 * (app.BeamHeight - app.FlangeThickness)^3);

            % MOI = 1/12 * ( (b*h^3) + (b_flange*h_flange^3
            
            
            results = 1;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes_2
        function UIAxes_2ButtonDown(app, event)

        end

        % Value changing function: BeamHeightinSlider
        function BeamHeightinSliderValueChanging(app, event)
            app.BeamHeight = event.Value;
           
            Update(app,1);
            
        end

        % Value changing function: BeamWidthinSlider
        function BeamWidthinSliderValueChanging(app, event)
            app.BeamWidth = event.Value;
             
            Update(app,1);
             
        end

        % Value changing function: WebThicknessinSlider
        function WebThicknessinSliderValueChanging(app, event)
            app.WebThickness = event.Value;
            
            Update(app,1);
            
        end

        % Value changing function: FlangeThicknessinSlider
        function FlangeThicknessinSliderValueChanging(app, event)
            app.FlangeThickness = event.Value;
                       
            Update(app,1);
              
        end

        % Callback function
        function MomentofInertiaEditFieldValueChanged(app, event)
            app.MomentofInertiaEditField.Value = app.BeamWidth;
            
        end

        % Value changing function: BeamLengthinSlider
        function BeamLengthinSliderValueChanging(app, event)
            app.BeamLength = event.Value;
            
            Update(app,2);
            
        end

        % Value changing function: LoadLocationinSlider
        function LoadLocationinSliderValueChanging(app, event)
            app.LoadLocation = event.Value + app.BeamLength/2;
            Update(app,2);
        end

        % Value changing function: LoadpoundsSlider
        function LoadpoundsSliderValueChanging(app, event)
            app.Load = event.Value;
            Update(app,2);
        end

        % Value changed function: MomentofInertiaEditField
        function MomentofInertiaEditFieldValueChanged2(app, event)
            value = app.MomentofInertiaEditField.Value;
            Update(app,2);
        end

        % Value changed function: MaterialDropDown
        function MaterialDropDownValueChanged(app, event)
            value = app.MaterialDropDown.Value;
            
            if(value == "Aluminum")
                app.ElasticModulus = 10*10^6;
            else if(value == "Steel")
                app.ElasticModulus = 30*10^6;
            else if (value == "Cast Iron")
                app.ElasticModulus = 16*10^6;
            else
                app.ElasticModulus = .6*10^6;
            end
            end 
            end
            
            Update(app,2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Beam Displacment')
            xlabel(app.UIAxes, 'in')
            ylabel(app.UIAxes, 'in')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-200 200];
            app.UIAxes.YLim = [-30 30];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Beam Cross Section')
            xlabel(app.UIAxes_2, 'in')
            ylabel(app.UIAxes_2, 'in')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XLim = [-15 15];
            app.UIAxes_2.YLim = [-15 15];
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.BeamLengthinSliderLabel = uilabel(app.UIFigure);
            app.BeamLengthinSliderLabel.Text = 'Beam Length (in)';
            app.BeamLengthinSlider = uislider(app.UIFigure);
            app.BeamLengthinSlider.Limits = [0 360];
            app.BeamLengthinSlider.ValueChangingFcn = createCallbackFcn(app, @BeamLengthinSliderValueChanging, true);
            app.BeamLengthinSlider.Value = 100;
            app.BeamHeightinSliderLabel = uilabel(app.UIFigure);
            app.BeamHeightinSliderLabel.Text = 'Beam Height (in)';
            app.BeamHeightinSlider = uislider(app.UIFigure);
            app.BeamHeightinSlider.Limits = [0 18];
            app.BeamHeightinSlider.ValueChangingFcn = createCallbackFcn(app, @BeamHeightinSliderValueChanging, true);
            app.BeamHeightinSlider.Value = 12;
            app.BeamWidthinSliderLabel = uilabel(app.UIFigure);
            app.BeamWidthinSliderLabel.Text = 'Beam Width(in)';
            app.BeamWidthinSlider = uislider(app.UIFigure);
            app.BeamWidthinSlider.Limits = [0 24];
            app.BeamWidthinSlider.ValueChangingFcn = createCallbackFcn(app, @BeamWidthinSliderValueChanging, true);
            app.BeamWidthinSlider.Value = 12;
            app.WebThicknessinSliderLabel = uilabel(app.UIFigure);
            app.WebThicknessinSliderLabel.Text = 'Web Thickness (in)';
            app.WebThicknessinSlider = uislider(app.UIFigure);
            app.WebThicknessinSlider.Limits = [0 12];
            app.WebThicknessinSlider.ValueChangingFcn = createCallbackFcn(app, @WebThicknessinSliderValueChanging, true);
            app.WebThicknessinSlider.Value = 2;
            app.FlangeThicknessinSliderLabel = uilabel(app.UIFigure);
            app.FlangeThicknessinSliderLabel.Text = 'Flange Thickness (in)';
            app.FlangeThicknessinSlider = uislider(app.UIFigure);
            app.FlangeThicknessinSlider.Limits = [0 24];
            app.FlangeThicknessinSlider.ValueChangingFcn = createCallbackFcn(app, @FlangeThicknessinSliderValueChanging, true);
            app.FlangeThicknessinSlider.Value = 2;
            app.LoadpoundsSliderLabel = uilabel(app.UIFigure);
            app.LoadpoundsSliderLabel.Text = 'Load (pounds)';
            app.LoadpoundsSlider = uislider(app.UIFigure);
            app.LoadpoundsSlider.Limits = [0 20000];
            app.LoadpoundsSlider.ValueChangingFcn = createCallbackFcn(app, @LoadpoundsSliderValueChanging, true);
            app.LoadLocationinSliderLabel = uilabel(app.UIFigure);
            app.LoadLocationinSliderLabel.Text = 'Load Location (in)';
            app.LoadLocationinSlider = uislider(app.UIFigure);
            app.LoadLocationinSlider.Limits = [-140 140];
            app.LoadLocationinSlider.ValueChangingFcn = createCallbackFcn(app, @LoadLocationinSliderValueChanging, true);
            app.MomentofInertiaEditFieldLabel = uilabel(app.UIFigure);
            app.MomentofInertiaEditFieldLabel.Text = 'Moment of Inertia';
            app.MomentofInertiaEditField = uieditfield(app.UIFigure, 'numeric');
            app.MomentofInertiaEditField.ValueChangedFcn = createCallbackFcn(app, @MomentofInertiaEditFieldValueChanged2, true);
            app.MomentofInertiaEditFieldLabel_2 = uilabel(app.UIFigure);
            app.MomentofInertiaEditFieldLabel_2.Text = 'in^4';
            app.MaterialDropDownLabel = uilabel(app.UIFigure);
            app.MaterialDropDownLabel.Text = 'Material';
            app.MaterialDropDown = uidropdown(app.UIFigure);
            app.MaterialDropDown.Items = {'Steel', 'Aluminum', 'Cast Iron', 'Wood'};
            app.MaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @MaterialDropDownValueChanged, true);
            app.MaterialDropDown.Value = 'Steel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BeamApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end